classdef cardio_raw_core < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CardioAlpha                     matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LoaddataPanel                   matlab.ui.container.Panel
        BrowseField                     matlab.ui.control.EditField
        LoadxlsxButton                  matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        pixelsizeLabel                  matlab.ui.control.Label
        PixelSizeEditField              matlab.ui.control.EditField
        umLabel                         matlab.ui.control.Label
        ModeSelector                    matlab.ui.control.Switch
        calibrationSwitchLabel          matlab.ui.control.Label
        calibrationSwitch               matlab.ui.control.Switch
        Lamp                            matlab.ui.control.Lamp
        LoadRAWButton                   matlab.ui.control.Button
        InterpolationPanel              matlab.ui.container.Panel
        InterpolationdegreeSliderLabel  matlab.ui.control.Label
        InterpolationdegreeSlider       matlab.ui.control.Slider
        ApplyInterpolationButton        matlab.ui.control.Button
        SaveWSmapButton                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        BiosensorimageTab               matlab.ui.container.Tab
        BiosensorAxes                   matlab.ui.control.UIAxes
        TimeminLabel                    matlab.ui.control.Label
        TimeminSlider                   matlab.ui.control.Slider
        ColorScaleLabel                 matlab.ui.control.Label
        CMaxEditField                   matlab.ui.control.NumericEditField
        CMinEditField                   matlab.ui.control.NumericEditField
        minLabel                        matlab.ui.control.Label
        maxLabel                        matlab.ui.control.Label
        SignalcorrectionTab             matlab.ui.container.Tab
        avg_kinetics_ax                 matlab.ui.control.UIAxes
        ResetcorrectionButton           matlab.ui.control.Button
        BackgroundcorrectionPanel       matlab.ui.container.Panel
        LocalbackgroundcorrectionButton  matlab.ui.control.Button
        GlobalbackgroundcorrectionButton  matlab.ui.control.Button
        BaselinecorrectionPanel         matlab.ui.container.Panel
        LinearcorrectionButton          matlab.ui.control.Button
        SinglecelldataTab               matlab.ui.container.Tab
        SingleCellAxes                  matlab.ui.control.UIAxes
        plotdataButtonGroup             matlab.ui.container.ButtonGroup
        maximalWSButton                 matlab.ui.control.RadioButton
        averageWSButton                 matlab.ui.control.RadioButton
        IWSButton                       matlab.ui.control.RadioButton
        areaButton                      matlab.ui.control.RadioButton
        ExportdataPanel                 matlab.ui.container.Panel
        EvalDataListBox                 matlab.ui.control.ListBox
        SavedataButton                  matlab.ui.control.Button
        CellIDPanel                     matlab.ui.container.Panel
        CellIDListBox                   matlab.ui.control.ListBox
        SigmoidfitPanel                 matlab.ui.container.Panel
        FitButton                       matlab.ui.control.Button
        ShowfitsCheckBox                matlab.ui.control.CheckBox
        ExportfitsCheckBox              matlab.ui.control.CheckBox
        CalibrationTab                  matlab.ui.container.Tab
        CalibrationTable                matlab.ui.control.Table
        CalibrationcellsListBoxLabel    matlab.ui.control.Label
        CalibrationcellsListBox         matlab.ui.control.ListBox
        LockselectionSwitch             matlab.ui.control.Switch
        CalculatethresholdsButton       matlab.ui.control.Button
        ExportcalibrationButton         matlab.ui.control.Button
        UniqueThAxes                    matlab.ui.control.UIAxes
        SinglecellevaluationPanel       matlab.ui.container.Panel
        EvaluateButton                  matlab.ui.control.Button
        ThModeButtonGroup               matlab.ui.container.ButtonGroup
        globalButton                    matlab.ui.control.RadioButton
        uniqueButton                    matlab.ui.control.RadioButton
        GlobalThresholdField            matlab.ui.control.EditField
        pmLabel_2                       matlab.ui.control.Label
        ThresholdingListBoxLabel        matlab.ui.control.Label
        UniqueEvalButtonGroup           matlab.ui.container.ButtonGroup
        HeLaRGDButton                   matlab.ui.control.RadioButton
        HeLaCombinedButton              matlab.ui.control.RadioButton
        CommunicationText               matlab.ui.control.TextArea
        FiltercellsPanel                matlab.ui.container.Panel
        FiltercellsButton               matlab.ui.control.StateButton
        SelectallButton                 matlab.ui.control.Button
        ResetselectionButton            matlab.ui.control.Button
        ShiftclickcellstoremovefromselectionLabel  matlab.ui.control.Label
        FindcellsPanel                  matlab.ui.container.Panel
        DetectButton                    matlab.ui.control.Button
        DetectionthresholdEditFieldLabel  matlab.ui.control.Label
        DetectionthresholdEditField     matlab.ui.control.NumericEditField
        pmLabel_3                       matlab.ui.control.Label
    end


    properties (Access = public)
        pic                     % The 3D data structure (2 spatial and 1 time) containing a single Cardio biosensor signal [for WS-pm, for WL-nm]
        time                    % Time vector from loaded data [s]
        mode                    % Evaluation mode: either 'WS'(wavelength-shift data in pm) or 'WL' (wavelength data in nm)
        raw_pix_size            % Raw (uninterpolated) pixel size of the instrument [um] (for EPIC Cardio: 25um, for EPIC BT: 80um)
        pic_corr                % drift corrected pic
        timestep                % length of averaged timestep [s]
        cMin=829                  % Colorbar minimum value [nm]
        cMax=832               % Colorbar maximum value [nm]
        avgWS                   % Whole sensor averaged wavelenght shift [pm]
        intpic                  % Interpolated data (normally also already baseline corrected)
        intpix                  % Interpolated pixel size [um]
        interpolation_done      % Check if interpolation has been applied yet
        uniqueTH                % vector holding unique thresholds for single cells [pm]
        detection_th=100        % threshold for cell detection given [pm]
        max_coord               % coordinates of single cell signal maxima [x-y pixel pairs]
        maxSHIFT                % maximal WL shifts of detected cells at the last timestep (used for uniqueTH calculation) [pm]
        cellLabels              % size(max_coord) long vector containing the numbering of detected cells [1:number of cells]
        target_coord            % coordinates of the target cells defined by user input [x-y pixel pairs]
        selected_cells          % vector containing the labels of selected cells (of target cells) [cell labels]
        VMask                   % Voronoi mask of last frame containing cell specific labels in each Voronoi cell [imagesize*imagesize matrix with cell label values]
        data                    % dataframe containing single cell data 
        sigfit_params           % parameters of fitted sigmoids for each datatype
        sigmoid_model           % function describing the fitted sigmoid
        shiftdown=0             % 1 if shift is pressed, 0 else
        cell_subselection       % subselection of selected cells for plotting and later export (cell array of labels)
        GlobCorrApp             % sub-application responsible for deteremining inverse baseline correction threshold value
        InverseBL_TH            % User defined threshold value for inverse baseline correction. Value is defined in sub-app:"global_bg_corr_app"
        LocalCorrApp            % sub-application for local background correction of cells
        LinCorrApp              % sub-application for linear baseline correction    
        calib_state             % is the app in calibration state or not ['on', 'off']
        calibration_data        % table that holds calibration data
        LoadRawApp              % subapp to load raw Cardio data
    end
    
    methods (Access = public)
        
        function init(app)
            %initialize variables
            app.pic=[];
            app.time=[];
            app.intpic=[];
            app.timestep=[];
            app.avgWS=[];
            app.uniqueTH=[];
            app.max_coord=[];
            app.maxSHIFT=[];
            app.cellLabels=[];
            app.target_coord=[];
            app.selected_cells=[];
            app.VMask=[];
            app.data=[];
            app.interpolation_done=false;
            app.sigfit_params = struct('maxWS',0,'avgWS',0,'IWS',0,'area',0);
            app.ShowfitsCheckBox.Value=0;
        end
        
        function refresh_target_plot(app,pic)
            % refresh content of BiosensorAxes
            cla(app.BiosensorAxes);
            hold(app.BiosensorAxes,'on');
            app.BiosensorAxes.XLim=[1 size(pic,1)];
            app.BiosensorAxes.YLim=[1 size(pic,1)];
            imagesc(app.BiosensorAxes,pic(:,:,end));
            if isempty(app.max_coord)
                app.CommunicationText.Value = 'No cells detected at given threshold level.';
            else
                % if existing, draw points at detected maxima
                scatter(app.BiosensorAxes,app.max_coord(:,1),app.max_coord(:,2),'r.');
                text(app.BiosensorAxes,app.max_coord(:,1),app.max_coord(:,2),num2str(app.cellLabels'),'VerticalAlignment','bottom');
            end
            if ~isempty(app.target_coord)
                % if existing, draw circles at selected maxima
                scatter(app.BiosensorAxes,app.target_coord(:,1),app.target_coord(:,2),100,'ro');
            end
            
        end
        
        function refresh_avg_kin_plot(app)
            cla(app.avg_kinetics_ax);
            hold(app.avg_kinetics_ax,'on');
            plot(app.avg_kinetics_ax,app.time./60,app.avgWS);
            if strcmp(app.mode,'WS')
                ylabel(app.avg_kinetics_ax,'average WS [pm]')
            else
                ylabel(app.avg_kinetics_ax,'average WL [nm]')
            end
            hold(app.avg_kinetics_ax,'off');
        end
        
        function refresh_single_cell_plot(app,data_selection,cell_subselection)
            if strcmp(data_selection,'maximal WS')
                selected_data=app.data.maxWS;
                selected_params=app.sigfit_params.maxWS;
                label='maximal WS (pm)';
            elseif strcmp(data_selection,'average WS')
                selected_data=app.data.WS_avgs;
                selected_params=app.sigfit_params.avgWS;
                label='average WS (pm)';
            elseif strcmp(data_selection,'IWS')
                selected_data=app.data.IWS_corr;
                selected_params=app.sigfit_params.IWS;
                label='IWS (pm*\mum^2)';
            elseif strcmp(data_selection,'area')
                selected_data=app.data.AREAs_corr;
                selected_params=app.sigfit_params.area;
                label='area (\mum^2)';
            end
            cla(app.SingleCellAxes);
            hold(app.SingleCellAxes,'on')
            
            colors=hsv(length(cell_subselection));
            for i=1:length(cell_subselection)
                ind=find(app.selected_cells==str2num(cell_subselection{i}));
                plot(app.SingleCellAxes,app.time./60,selected_data(ind,:),'Color',colors(i,:));
                if app.ShowfitsCheckBox.Value
                    p2=plot(app.SingleCellAxes,app.time./60,app.sigmoid_model(selected_params(ind,:),app.time),'Color',colors(i,:));
                    p2.Annotation.LegendInformation.IconDisplayStyle = 'off';
                end
            end
            xlim(app.SingleCellAxes,[app.time(1)./60,app.time(end)./60]);
            ylabel(app.SingleCellAxes,label);
            legend(app.SingleCellAxes,cell_subselection,'Location','northwest');
            colormap(app.SingleCellAxes,'colorcube');
            hold(app.SingleCellAxes,'off')
        end

        function update_colorscale(app,pic)
            % set colorscale boundaries based on the current max and min values of pic(:,:,end)
            if isempty(pic)
                % in case the user closed the correction window without correction, pic_corr is not created... this statement handles it
                return
            end
            app.cMin=double(min(pic(:,:,end),[],'all'));
            app.cMax=double(max(pic(:,:,end),[],'all'));
            app.CMinEditField.Value=app.cMin;
            app.CMaxEditField.Value=app.cMax;
            caxis(app.BiosensorAxes,[app.cMin,app.cMax]);
        end

        
        function update_calib_table(app)
            value = app.CalibrationcellsListBox.Value';
            indies = str2num(cell2mat(value));
            maxvalues = num2cell(app.maxSHIFT(indies'));
            nan_value = num2cell(nan(size(value))); %add nans as placeholders
            app.CalibrationTable.Data = [value,nan_value,maxvalues,nan_value];
            app.calibration_data = app.CalibrationTable.Data;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init();
            
            %add elements to figure at startup
            colorbar(app.BiosensorAxes);                        %add colorbar to axes
            caxis(app.BiosensorAxes,[app.cMin,app.cMax]);       %set colorbar limits
            
            %disable interactivity of BiosensorAxes
            app.BiosensorAxes.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.BiosensorAxes);
            set(app.BiosensorAxes,'visible','off')
            
            colormap(app.BiosensorAxes,"parula");               %set colorscale
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx');                  %get file path
            cd(path);                                           %change default path
            app.BrowseField.Value = fullfile(path,file); 
        end

        % Value changed function: ModeSelector
        function ModeSelectorValueChanged(app, event)
            app.mode = app.ModeSelector.Value;
        end

        % Button pushed function: LoadxlsxButton
        function LoadxlsxButtonPushed(app, event)
            init(app);  %initialize variables
            d = uiprogressdlg(app.CardioAlpha,'Title','Please Wait','Message','Loading data...','Indeterminate','on');
            app.mode = app.ModeSelector.Value;  % set mode (WS or WL input)
            [app.pic,app.time,~] = load_data(app.BrowseField.Value); % load raw data
            app.raw_pix_size = str2num(app.PixelSizeEditField.Value);
            app.timestep=app.time(end)/size(app.time,1);
            app.refresh_target_plot(app.pic);
            app.update_colorscale(app.pic);
            % set TimeSlider limits based on loaded time
            app.TimeminSlider.Limits=[1,floor(app.time(end)/60)];
            app.TimeminSlider.Value=app.TimeminSlider.Limits(2);
            
            %calculate average kinetics
            for i=1:size(app.time,1)
                app.avgWS(i)=mean(reshape(app.pic(:,:,i),1,[]));
            end
            close(d);
            refresh_avg_kin_plot(app)
            app.TabGroup.SelectedTab = app.SignalcorrectionTab; % change current tab to SignalcorrectionTab
            app.CommunicationText.Value= 'Correct the raw signal!';
        end

        % Button pushed function: LoadRAWButton
        function LoadRAWButtonPushed(app, event)
            app.LoadRAWButton.Enable = 'off';
            app.LoadRawApp = cardio_raw_app(app);
            uiwait(app.LoadRawApp.UIFigure);
            
            size(app.pic)
            
            app.raw_pix_size = str2num(app.PixelSizeEditField.Value);
            app.timestep=app.time(end)/size(app.time,1);
            app.refresh_target_plot(app.pic);
            app.update_colorscale(app.pic);
            % set TimeSlider limits based on loaded time
            app.TimeminSlider.Limits=[1,floor(app.time(end)/60)];
            app.TimeminSlider.Value=app.TimeminSlider.Limits(2);
            
            %calculate average kinetics
            for i=1:size(app.time,1)
                app.avgWS(i)=mean(reshape(app.pic(:,:,i),1,[]));
            end
            refresh_avg_kin_plot(app)
            app.TabGroup.SelectedTab = app.SignalcorrectionTab; % change current tab to SignalcorrectionTab
            app.CommunicationText.Value= 'Correct the raw signal!';
        end

        % Value changing function: TimeminSlider
        function TimeminSliderValueChanging(app, event)
            %since the timesteps are sometimes not equal, it is possible that the index produced below is out of range... 
            %the exception  below handles it:
            if round(event.Value*(60/app.timestep))>size(app.time,1)
                app.TimeminSlider.Value=floor(app.time(end)/60);
            end
            
            if isempty(app.pic_corr)
                imagesc(app.pic(:,:,floor(event.Value*(60/app.timestep))),'Parent',app.BiosensorAxes);
            elseif isempty(app.intpic)
                imagesc(app.pic_corr(:,:,floor(event.Value*(60/app.timestep))),'Parent',app.BiosensorAxes);
            else
                imagesc(app.intpic(:,:,floor(event.Value*(60/app.timestep))),'Parent',app.BiosensorAxes);
            end
        end

        % Value changed function: CMaxEditField
        function CMaxEditFieldValueChanged(app, event)
            % Change colorscale max value
            app.cMax = app.CMaxEditField.Value;
            caxis(app.BiosensorAxes,[app.cMin,app.cMax]);
        end

        % Value changed function: CMinEditField
        function CMinEditFieldValueChanged(app, event)
            % Change colorscale min value
            app.cMin = app.CMinEditField.Value;
            caxis(app.BiosensorAxes,[app.cMin,app.cMax]);
        end

        % Button pushed function: LinearcorrectionButton
        function LinearcorrectionButtonPushed(app, event)
            app.LinearcorrectionButton.Enable = 'off';
            app.LinCorrApp = lin_corr_app(app,app.time,app.avgWS);
            uiwait(app.LinCorrApp.UIFigure);
            if ~isempty(app.pic_corr)
                imagesc(app.pic_corr(:,:,floor(app.TimeminSlider.Value*(60/app.timestep))),'Parent',app.BiosensorAxes);
            end
            app.update_colorscale(app.pic_corr);
        end

        % Button pushed function: GlobalbackgroundcorrectionButton
        function GlobalbackgroundcorrectionButtonPushed(app, event)
            app.GlobalbackgroundcorrectionButton.Enable = 'off';
            app.GlobCorrApp = global_bg_corr_app(app,squeeze(app.pic(:,:,end)));
            uiwait(app.GlobCorrApp.UIFigure);
            if ~isempty(app.pic_corr)
                imagesc(app.pic_corr(:,:,floor(app.TimeminSlider.Value*(60/app.timestep))),'Parent',app.BiosensorAxes);
            end
            app.update_colorscale(app.pic_corr);
        end

        % Button pushed function: LocalbackgroundcorrectionButton
        function LocalbackgroundcorrectionButtonPushed(app, event)
            app.LocalbackgroundcorrectionButton.Enable = 'off';
            app.LocalCorrApp = local_bg_corr_app(app,app.pic);
            uiwait(app.LocalCorrApp.UIFigure);
            if ~isempty(app.pic_corr)
                imagesc(app.pic_corr(:,:,floor(app.TimeminSlider.Value*(60/app.timestep))),'Parent',app.BiosensorAxes);
            end
            app.update_colorscale(app.pic_corr);
        end

        % Button pushed function: ResetcorrectionButton
        function ResetcorrectionButtonPushed(app, event)
            %reset pic_corr to original pic, and avgWS to average of original pic
            app.pic_corr = app.pic;
            for t=1:size(app.pic,3)
                app.avgWS(t)= mean(app.pic(:,:,t),'all');
            end
            app.mode= app.ModeSelector.Value; %reset mode too, since correction can change it
            app.refresh_avg_kin_plot();
            imagesc(app.pic_corr(:,:,floor(app.TimeminSlider.Value*(60/app.timestep))),'Parent',app.BiosensorAxes);
        end

        % Value changed function: InterpolationdegreeSlider
        function InterpolationdegreeSliderValueChanged(app, event)
            app.InterpolationdegreeSlider.Value = round(app.InterpolationdegreeSlider.Value);
        end

        % Button pushed function: ApplyInterpolationButton
        function ApplyInterpolationButtonPushed(app, event)
            app.target_coord=[];
            [app.intpic,app.intpix]=interpolate_pic(app.InterpolationdegreeSlider.Value,app.pic_corr,app.raw_pix_size); % calculate interpolated data
            imagesc(app.intpic(:,:,floor(app.TimeminSlider.Value*(60/app.timestep))),'Parent',app.BiosensorAxes);
            app.BiosensorAxes.XLim=[1 size(app.intpic,1)];
            app.BiosensorAxes.YLim=[1 size(app.intpic,1)];
            app.interpolation_done=true;
            app.TabGroup.SelectedTab=app.BiosensorimageTab;
            app.CommunicationText.Value= 'Interpolation done!';
        end

        % Value changed function: DetectionthresholdEditField
        function DetectionthresholdEditFieldValueChanged(app, event)
            app.detection_th = app.DetectionthresholdEditField.Value;
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
%             [app.max_coord,app.cellLabels,app.VMask,app.maxSHIFT] = detect_cells(app.detection_th,app.intpic,app.intpix);
            [app.max_coord,app.cellLabels,app.VMask,app.maxSHIFT] = detect_cells(app.DetectionthresholdEditField.Value,app.intpic,app.intpix);
            app.cellLabels
            if isempty(app.max_coord)
                app.CommunicationText.Value= 'No cells were detected with given threshold...detection failed.';
            else
                app.refresh_target_plot(app.intpic);
                app.CommunicationText.Value= 'Detection done! Filter cells for evaluation!';
            end
            app.cellLabels
            app.CalibrationcellsListBox.Items = cellstr(num2str(app.cellLabels'));
            app.update_calib_table()
        end

        % Value changed function: FiltercellsButton
        function FiltercellsButtonValueChanged(app, event)
            state = app.FiltercellsButton.Value; % state=1 if pushed, else 0   
            if state==1
                app.CommunicationText.Value= 'Select cells on sensor image!';
            end
            if state==0
                app.CommunicationText.Value= 'Selection done.';
            end
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
            app.target_coord=app.max_coord;
            app.selected_cells=(1:size(app.max_coord,1))';
            app.refresh_target_plot(app.intpic);
        end

        % Button pushed function: ResetselectionButton
        function ResetselectionButtonPushed(app, event)
            app.target_coord=[];
            app.refresh_target_plot(app.intpic);
        end

        % Key press function: CardioAlpha
        function CardioAlphaKeyPress(app, event)
            %detect shift press for removing cells functionality
            
            if strcmp(event.Key,'shift')
                app.shiftdown=1;
            end
        end

        % Key release function: CardioAlpha
        function CardioAlphaKeyRelease(app, event)
            %detect shift press for removing cells functionality
            
            if strcmp(event.Key,'shift')
                app.shiftdown=0;
            end
        end

        % Window button down function: CardioAlpha
        function CardioAlphaWindowButtonDown(app, event)
            %detect clicks on the figure for cell selection functionality
            
            tmp= app.BiosensorAxes.CurrentPoint;
            %create difference vector with tmp and max_coord
            accept_point=0;
            closest_cell_label=[];
            sensitivity=0.05; %distance given as the percentage of the map size within a click on a cell is considered to be valid
            for i=1:size(app.max_coord,1)
                if norm(app.max_coord(i,:)-[tmp(1,1) tmp(1,2)])<sensitivity*size(app.intpic,1)
                    accept_point=1;
                    closest_cell_label=i;
                    break
                end
            end
            if app.FiltercellsButton.Value==1 && accept_point %if we are in filtering mode and click on cell was valid
                if app.shiftdown==0
                    %adding cells to selection
                    app.target_coord=unique([app.target_coord;app.max_coord(closest_cell_label,:)],'rows','stable');
                    [~,app.selected_cells,~]= intersect(app.max_coord,app.target_coord,'rows');
                elseif app.shiftdown==1
                    %removing cells to selection
                    [~,row,~]= intersect(app.target_coord,app.max_coord(closest_cell_label,:),'rows');
                    app.target_coord(row,:)=[];
                    app.selected_cells(app.selected_cells==closest_cell_label)=[];
                end
                app.refresh_target_plot(app.intpic)
            end
        end

        % Selection changed function: ThModeButtonGroup
        function ThModeButtonGroupSelectionChanged(app, event)
            if strcmp(app.ThModeButtonGroup.SelectedObject.Text,'global')
                %enable global thresholding
                app.GlobalThresholdField.Editable=true;
                app.UniqueEvalButtonGroup.Visible=false;
            else
                %for unique thresholding disable fields
                app.GlobalThresholdField.Editable=false;
                app.GlobalThresholdField.Value='';
                app.UniqueEvalButtonGroup.Visible=true;
            end
        end

        % Value changed function: GlobalThresholdField
        function GlobalThresholdFieldValueChanged(app, event)
            % by setting colorbar values we will get an image that is saturated under and over the global value, indicating cell areas
            app.CMinEditField.Value = str2double(app.GlobalThresholdField.Value);
            app.CMaxEditField.Value = str2double(app.GlobalThresholdField.Value)+1;
            caxis(app.BiosensorAxes,[app.CMinEditField.Value,app.CMaxEditField.Value]);
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            if strcmp(app.ThModeButtonGroup.SelectedObject.Text,'global')
                % if global evaluation is selected, set uniqueTH to the same value given in GlobalThresholdField
                app.uniqueTH=repmat(str2num(app.GlobalThresholdField.Value),size(app.maxSHIFT));
            else
                % if unique evaluation is selected, calculate uniqueTHs according to the selected calibration relation
                if app.HeLaRGDButton.Value==1
                    load('MM_fit.mat','MM_fit');                    % load calibration function parameters
                    app.uniqueTH=feval(MM_fit,app.maxSHIFT);        % calculate uniqueTHs
                elseif app.HeLaCombinedButton.Value==1
                    load('hela_combined_MM_fit.mat','HeLaCombinedFit');
                    app.uniqueTH=feval(HeLaCombinedFit,app.maxSHIFT);
                end
            end
            
            %plot unique thresholded areas on last frame for comparison
            unique_th_frame = zeros(size(app.intpix));
            for i=1:size(app.selected_cells,1)
                app.selected_cells(i)
                calib_cell = app.intpic(:,:,end).*(app.VMask==app.selected_cells(i));
                unique_th_frame=unique_th_frame+app.selected_cells(i)*(calib_cell>app.uniqueTH(i));
            end
            
            unique_th_frame = flipud(unique_th_frame); %for correct orientation
%             i=21;
%             calib_cell = app.intpic(:,:,end).*(app.VMask==app.selected_cells(i));
%             unique_th_frame=unique_th_frame+(calib_cell>app.uniqueTH(i));

            fig = uifigure('Name','Unique thresholding on last frame');
            ax = axes(fig);
            imagesc(ax,flipud(unique_th_frame)); %unique_th_frame
            unique_th_frame;
            pbaspect(ax,[1 1 1]);
            colorbar(ax,"east");
            
            
            %calculate single cell data
            app.data=CellEval(app.intpic,app.VMask,app.time,app.intpix,app.uniqueTH,app.selected_cells,app.CardioAlpha);
            app.CommunicationText.Value= 'Evaluation done!';
            
            app.CellIDListBox.Items= cellstr(num2str(app.selected_cells));
            app.CellIDListBox.Value = app.CellIDListBox.Items;
            
            app.cell_subselection = sort(app.CellIDListBox.Value);
            app.ShowfitsCheckBox.Value=0;
            app.ExportfitsCheckBox.Value=0;
            app.refresh_single_cell_plot(app.plotdataButtonGroup.SelectedObject.Text, app.CellIDListBox.Value);
            app.TabGroup.SelectedTab=app.SinglecelldataTab;
        end

        % Selection changed function: plotdataButtonGroup
        function plotdataButtonGroupSelectionChanged(app, event)
            selection=app.plotdataButtonGroup.SelectedObject.Text;
            app.refresh_single_cell_plot(selection,app.cell_subselection);
        end

        % Value changed function: CellIDListBox
        function CellIDListBoxValueChanged(app, event)
            app.cell_subselection = sort(app.CellIDListBox.Value);
            app.refresh_single_cell_plot(app.plotdataButtonGroup.SelectedObject.Text,app.cell_subselection);
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            %fit sigmoids to data
            
            [app.sigfit_params.maxWS,~,app.sigmoid_model] = sigmoid_fit(app.time,app.data.maxWS);
            app.sigfit_params.IWS = sigmoid_fit(app.time,app.data.IWS_corr);
            app.sigfit_params.avgWS = sigmoid_fit(app.time,app.data.WS_avgs);
            app.sigfit_params.area = sigmoid_fit(app.time,app.data.AREAs_corr);
            app.CommunicationText.Value= 'Sigmoid fitting done!';
            app.ShowfitsCheckBox.Value=1;
            app.ExportfitsCheckBox.Value=1;
            app.refresh_single_cell_plot(app.plotdataButtonGroup.SelectedObject.Text,app.cell_subselection);
        end

        % Value changed function: ShowfitsCheckBox
        function ShowfitsCheckBoxValueChanged(app, event)
            app.refresh_single_cell_plot(app.plotdataButtonGroup.SelectedObject.Text,app.cell_subselection);
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            %export data of different modalities
            
            [filenum,path] = uiputfile('1.xlsx');
            cmp_vec=[];
            for i=1:length(app.cell_subselection)
                cmp_vec(i)=str2num(app.cell_subselection{i});
            end
            ind=find(ismember(app.selected_cells,cmp_vec));
            
            if any(strcmp(app.EvalDataListBox.Value,'IWS'))
                filename=strcat(path,'IWS_',filenum);
                xlswrite(filename,format_export(app.data.IWS(ind,:),app.selected_cells(ind),app.time)');
                if app.ExportfitsCheckBox.Value
                    format_export_param(app.sigfit_params.IWS(ind,:),filename,app.selected_cells(ind),'IWS')
                end
            end
            
            if any(strcmp(app.EvalDataListBox.Value,'average WS'))
                filename=strcat(path,'avgWS_',filenum);
                xlswrite(filename,format_export(app.data.WS_avgs(ind,:),app.selected_cells(ind),app.time)');
                if app.ExportfitsCheckBox.Value
                    format_export_param(app.sigfit_params.avgWS(ind,:),filename,app.selected_cells(ind),'avgWS')
                end
            end
            
            if any(strcmp(app.EvalDataListBox.Value,'maximal WS'))
                filename=strcat(path,'maxWS_',filenum);
                xlswrite(filename,format_export(app.data.maxWS(ind,:),app.selected_cells(ind),app.time)');
                if app.ExportfitsCheckBox.Value
                    format_export_param(app.sigfit_params.maxWS(ind,:),filename,app.selected_cells(ind),'maxWS')
                end
            end
            
            if any(strcmp(app.EvalDataListBox.Value,'area'))
                filename=strcat(path,'area_',filenum);
                xlswrite(filename,format_export(app.data.AREAs_corr(ind,:),app.selected_cells(ind),app.time)');
                if app.ExportfitsCheckBox.Value
                    format_export_param(app.sigfit_params.area(ind,:),filename,app.selected_cells(ind),'area')
                end
            end
            app.CommunicationText.Value = 'Export finished!';
        end

        % Value changed function: calibrationSwitch
        function calibrationSwitchValueChanged(app, event)
            app.calib_state = app.Lamp.Enable;
            if strcmp(app.calib_state,'off')
                app.calib_state='on';
                app.SinglecellevaluationPanel.Visible = 0;
            else
                app.calib_state='off';
                app.SinglecellevaluationPanel.Visible = 1;
            end
            app.Lamp.Enable = app.calib_state;
            
        end

        % Value changed function: CalibrationcellsListBox
        function CalibrationcellsListBoxValueChanged(app, event)
%             value = app.CalibrationcellsListBox.Value';
%             indies = str2num(cell2mat(value));
%             maxvalues = num2cell(app.maxSHIFT(indies'));
%             nan_value = num2cell(nan(size(value))); %add nans as placeholders
%             app.CalibrationTable.Data = [value,nan_value,maxvalues,nan_value];
%             app.calibration_data = app.CalibrationTable.Data;
              app.update_calib_table()
        end

        % Cell edit callback: CalibrationTable
        function CalibrationTableCellEdit(app, event)
            app.calibration_data = app.CalibrationTable.Data;
            app.calibration_data
            
        end

        % Display data changed function: CalibrationTable
        function CalibrationTableDisplayDataChanged(app, event)
            newDisplayData = app.CalibrationTable.DisplayData;
            
        end

        % Value changed function: LockselectionSwitch
        function LockselectionSwitchValueChanged(app, event)
            value = app.LockselectionSwitch.Value;
            if strcmp(value,'Edit')
                app.CalibrationcellsListBox.Enable=true;
            else
                app.CalibrationcellsListBox.Enable=false;
                app.CommunicationText.Value = 'Enter measured areas of calibration cells (in um2)!' ;
            end
            
        end

        % Button pushed function: CalculatethresholdsButton
        function CalculatethresholdsButtonPushed(app, event)
            last_frame = squeeze(app.intpic(:,:,end));
            app.max_coord;
            imagesc(app.UniqueThAxes,app.intpic(:,:,end));
            size(app.calibration_data)
            app.calibration_data = th_opt(app.calibration_data,last_frame,app.VMask,100,app.intpix);
            app.CalibrationTable.Data = app.calibration_data;
            
            %plot on UniqueThAxes
            unique_th_frame = zeros(size(app.intpix));
            for i=1:size(app.calibration_data,1)
                cell_id = str2num(cell2mat(app.calibration_data(i,1)));
                calib_cell = last_frame.*(app.VMask==cell_id);
                unique_th_frame=unique_th_frame+(calib_cell>cell2mat(app.calibration_data(i,5)));
            end
%             unique_th_frame = flipud(unique_th_frame); %for correct orientation
            imagesc(app.UniqueThAxes,unique_th_frame);
        end

        % Button pushed function: ExportcalibrationButton
        function ExportcalibrationButtonPushed(app, event)
            [filenum,path] = uiputfile('1.xlsx');
            filename=strcat(path,'th_calib_',filenum);
            header = {'Cell ID','measured area (um2)','last max WS(pm)','thresholded area(um2)','corresponding threhold (pm)'};
            xlswrite(filename,[header;app.calibration_data]);
        end

        % Selection changed function: UniqueEvalButtonGroup
        function UniqueEvalButtonGroupSelectionChanged(app, event)
            selectedButton = app.UniqueEvalButtonGroup.SelectedObject
            app.HeLaRGDButton.Value
        end

        % Button pushed function: SaveWSmapButton
        function SaveWSmapButtonPushed(app, event)
            [filnamename,path] = uiputfile('WS_map.mat');
            filepath=strcat(path,filnamename);
            filepath_pix=strcat(filepath,'pix',filnamename);
            intpic_save = app.intpic;
            intpix_save = app.intpix;
            save(filepath,'intpic_save');
            save(filepath_pix,'intpix_save');
        end

        % Callback function
        function SavefiguresButtonPushed(app, event)
            avg_ax = app.avg_kinetics_ax;
            single_cell_ax = app.SingleCellAxes;
            copyUIAxes(avg_ax);
            copyUIAxes(single_cell_ax);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CardioAlpha = uifigure('Visible', 'off');
            app.CardioAlpha.Name = 'Cardio Alpha 0.6';
            app.CardioAlpha.WindowButtonDownFcn = createCallbackFcn(app, @CardioAlphaWindowButtonDown, true);
            app.CardioAlpha.KeyPressFcn = createCallbackFcn(app, @CardioAlphaKeyPress, true);
            app.CardioAlpha.KeyReleaseFcn = createCallbackFcn(app, @CardioAlphaKeyRelease, true);
            app.GridLayout = uigridlayout(app.CardioAlpha);
            app.LoaddataPanel = uipanel(app.GridLayout);
            app.LoaddataPanel.Title = 'Load data';
            app.BrowseField = uieditfield(app.LoaddataPanel, 'text');
            app.LoadxlsxButton = uibutton(app.LoaddataPanel, 'push');
            app.LoadxlsxButton.ButtonPushedFcn = createCallbackFcn(app, @LoadxlsxButtonPushed, true);
            app.LoadxlsxButton.Text = 'Load xlsx';
            app.BrowseButton = uibutton(app.LoaddataPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.pixelsizeLabel = uilabel(app.LoaddataPanel);
            app.pixelsizeLabel.Text = 'pixel size:';
            app.PixelSizeEditField = uieditfield(app.LoaddataPanel, 'text');
            app.PixelSizeEditField.Value = '25';
            app.umLabel = uilabel(app.LoaddataPanel);
            app.umLabel.Text = 'um';
            app.ModeSelector = uiswitch(app.LoaddataPanel, 'slider');
            app.ModeSelector.Items = {'WS', 'WL'};
            app.ModeSelector.ValueChangedFcn = createCallbackFcn(app, @ModeSelectorValueChanged, true);
            app.ModeSelector.Value = 'WS';
            app.calibrationSwitchLabel = uilabel(app.LoaddataPanel);
            app.calibrationSwitchLabel.Text = 'calibration';
            app.calibrationSwitch = uiswitch(app.LoaddataPanel, 'slider');
            app.calibrationSwitch.Items = {'off', 'on'};
            app.calibrationSwitch.ValueChangedFcn = createCallbackFcn(app, @calibrationSwitchValueChanged, true);
            app.calibrationSwitch.Value = 'off';
            app.Lamp = uilamp(app.LoaddataPanel);
            app.Lamp.Enable = 'off';
            app.LoadRAWButton = uibutton(app.LoaddataPanel, 'push');
            app.LoadRAWButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRAWButtonPushed, true);
            app.LoadRAWButton.Text = 'Load RAW';
            app.InterpolationPanel = uipanel(app.GridLayout);
            app.InterpolationPanel.Title = 'Interpolation';
            app.InterpolationdegreeSliderLabel = uilabel(app.InterpolationPanel);
            app.InterpolationdegreeSliderLabel.Text = 'Interpolation degree';
            app.InterpolationdegreeSlider = uislider(app.InterpolationPanel);
            app.InterpolationdegreeSlider.Limits = [0 3];
            app.InterpolationdegreeSlider.MajorTicks = [0 1 2 3];
            app.InterpolationdegreeSlider.ValueChangedFcn = createCallbackFcn(app, @InterpolationdegreeSliderValueChanged, true);
            app.InterpolationdegreeSlider.MinorTicks = [];
            app.ApplyInterpolationButton = uibutton(app.InterpolationPanel, 'push');
            app.ApplyInterpolationButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyInterpolationButtonPushed, true);
            app.ApplyInterpolationButton.Text = 'Apply';
            app.SaveWSmapButton = uibutton(app.InterpolationPanel, 'push');
            app.SaveWSmapButton.ButtonPushedFcn = createCallbackFcn(app, @SaveWSmapButtonPushed, true);
            app.SaveWSmapButton.Text = 'Save WS map';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.BiosensorimageTab = uitab(app.TabGroup);
            app.BiosensorimageTab.Title = 'Biosensor image';
            app.BiosensorAxes = uiaxes(app.BiosensorimageTab);
            app.BiosensorAxes.YDir = 'reverse';
            app.BiosensorAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.TimeminLabel = uilabel(app.BiosensorimageTab);
            app.TimeminLabel.Text = {'Time'; '(min)'};
            app.TimeminSlider = uislider(app.BiosensorimageTab);
            app.TimeminSlider.Limits = [1 100];
            app.TimeminSlider.ValueChangingFcn = createCallbackFcn(app, @TimeminSliderValueChanging, true);
            app.TimeminSlider.MinorTicks = [];
            app.TimeminSlider.Value = 1;
            app.ColorScaleLabel = uilabel(app.BiosensorimageTab);
            app.ColorScaleLabel.Text = 'ColorScale';
            app.CMaxEditField = uieditfield(app.BiosensorimageTab, 'numeric');
            app.CMaxEditField.ValueChangedFcn = createCallbackFcn(app, @CMaxEditFieldValueChanged, true);
            app.CMaxEditField.Value = 1500;
            app.CMinEditField = uieditfield(app.BiosensorimageTab, 'numeric');
            app.CMinEditField.ValueChangedFcn = createCallbackFcn(app, @CMinEditFieldValueChanged, true);
            app.minLabel = uilabel(app.BiosensorimageTab);
            app.minLabel.Text = 'min.';
            app.maxLabel = uilabel(app.BiosensorimageTab);
            app.maxLabel.Text = 'max.';
            app.SignalcorrectionTab = uitab(app.TabGroup);
            app.SignalcorrectionTab.Title = 'Signal correction';
            app.avg_kinetics_ax = uiaxes(app.SignalcorrectionTab);
            xlabel(app.avg_kinetics_ax, 'time [min]')
            ylabel(app.avg_kinetics_ax, 'average WS [pm]')
            app.ResetcorrectionButton = uibutton(app.SignalcorrectionTab, 'push');
            app.ResetcorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetcorrectionButtonPushed, true);
            app.ResetcorrectionButton.Text = 'Reset correction';
            app.BackgroundcorrectionPanel = uipanel(app.SignalcorrectionTab);
            app.BackgroundcorrectionPanel.Title = 'Background correction';
            app.LocalbackgroundcorrectionButton = uibutton(app.BackgroundcorrectionPanel, 'push');
            app.LocalbackgroundcorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @LocalbackgroundcorrectionButtonPushed, true);
            app.LocalbackgroundcorrectionButton.Text = 'Local background correction...';
            app.GlobalbackgroundcorrectionButton = uibutton(app.BackgroundcorrectionPanel, 'push');
            app.GlobalbackgroundcorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @GlobalbackgroundcorrectionButtonPushed, true);
            app.GlobalbackgroundcorrectionButton.Text = 'Global background correction...';
            app.BaselinecorrectionPanel = uipanel(app.SignalcorrectionTab);
            app.BaselinecorrectionPanel.Title = 'Baseline correction';
            app.LinearcorrectionButton = uibutton(app.BaselinecorrectionPanel, 'push');
            app.LinearcorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @LinearcorrectionButtonPushed, true);
            app.LinearcorrectionButton.Text = 'Linear correction...';
            app.SinglecelldataTab = uitab(app.TabGroup);
            app.SinglecelldataTab.Title = 'Single cell data';
            app.SingleCellAxes = uiaxes(app.SinglecelldataTab);
            title(app.SingleCellAxes, '')
            xlabel(app.SingleCellAxes, 'time (min)')
            ylabel(app.SingleCellAxes, '')
            app.plotdataButtonGroup = uibuttongroup(app.SinglecelldataTab);
            app.plotdataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @plotdataButtonGroupSelectionChanged, true);
            app.plotdataButtonGroup.Title = 'plot data';
            app.maximalWSButton = uiradiobutton(app.plotdataButtonGroup);
            app.maximalWSButton.Text = 'maximal WS';
            app.maximalWSButton.Value = true;
            app.averageWSButton = uiradiobutton(app.plotdataButtonGroup);
            app.averageWSButton.Text = 'average WS';
            app.IWSButton = uiradiobutton(app.plotdataButtonGroup);
            app.IWSButton.Text = 'IWS';
            app.areaButton = uiradiobutton(app.plotdataButtonGroup);
            app.areaButton.Text = 'area';
            app.ExportdataPanel = uipanel(app.SinglecelldataTab);
            app.ExportdataPanel.Title = 'Export data';
            app.EvalDataListBox = uilistbox(app.ExportdataPanel);
            app.EvalDataListBox.Items = {'maximal WS', 'average WS', 'IWS', 'area'};
            app.EvalDataListBox.Multiselect = 'on';
            app.EvalDataListBox.Value = {'maximal WS'};
            app.SavedataButton = uibutton(app.ExportdataPanel, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            app.CellIDPanel = uipanel(app.SinglecelldataTab);
            app.CellIDPanel.Title = 'Cell ID';
            app.CellIDListBox = uilistbox(app.CellIDPanel);
            app.CellIDListBox.Items = {'1', '2', '3'};
            app.CellIDListBox.Multiselect = 'on';
            app.CellIDListBox.ValueChangedFcn = createCallbackFcn(app, @CellIDListBoxValueChanged, true);
            app.CellIDListBox.Value = {'1'};
            app.SigmoidfitPanel = uipanel(app.SinglecelldataTab);
            app.SigmoidfitPanel.Title = 'Sigmoid fit';
            app.FitButton = uibutton(app.SigmoidfitPanel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.ShowfitsCheckBox = uicheckbox(app.SigmoidfitPanel);
            app.ShowfitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowfitsCheckBoxValueChanged, true);
            app.ShowfitsCheckBox.Text = 'Show fits';
            app.ExportfitsCheckBox = uicheckbox(app.SigmoidfitPanel);
            app.ExportfitsCheckBox.Text = 'Export fits';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.CalibrationTable = uitable(app.CalibrationTab);
            app.CalibrationTable.ColumnName = {'Cell ID'; 'measured area (um2)'; 'last max WS(pm)'; 'thresholded area(um2)'; 'corresponding threhold (pm)'};
            app.CalibrationTable.RowName = {};
            app.CalibrationTable.ColumnSortable = true;
            app.CalibrationTable.ColumnEditable = true;
            app.CalibrationTable.CellEditCallback = createCallbackFcn(app, @CalibrationTableCellEdit, true);
            app.CalibrationTable.DisplayDataChangedFcn = createCallbackFcn(app, @CalibrationTableDisplayDataChanged, true);
            app.CalibrationcellsListBoxLabel = uilabel(app.CalibrationTab);
            app.CalibrationcellsListBoxLabel.Text = 'Calibration cells';
            app.CalibrationcellsListBox = uilistbox(app.CalibrationTab);
            app.CalibrationcellsListBox.Items = {'1', '2', '3'};
            app.CalibrationcellsListBox.Multiselect = 'on';
            app.CalibrationcellsListBox.ValueChangedFcn = createCallbackFcn(app, @CalibrationcellsListBoxValueChanged, true);
            app.CalibrationcellsListBox.Value = {'1'};
            app.LockselectionSwitch = uiswitch(app.CalibrationTab, 'slider');
            app.LockselectionSwitch.Items = {'Locked', 'Edit'};
            app.LockselectionSwitch.ValueChangedFcn = createCallbackFcn(app, @LockselectionSwitchValueChanged, true);
            app.LockselectionSwitch.Value = 'Edit';
            app.CalculatethresholdsButton = uibutton(app.CalibrationTab, 'push');
            app.CalculatethresholdsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatethresholdsButtonPushed, true);
            app.CalculatethresholdsButton.Text = 'Calculate thresholds';
            app.ExportcalibrationButton = uibutton(app.CalibrationTab, 'push');
            app.ExportcalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @ExportcalibrationButtonPushed, true);
            app.ExportcalibrationButton.Text = 'Export calibration';
            app.UniqueThAxes = uiaxes(app.CalibrationTab);
            title(app.UniqueThAxes, 'Unique thresholded areas')
            xlabel(app.UniqueThAxes, '')
            ylabel(app.UniqueThAxes, '')
            app.UniqueThAxes.XTick = [0 1];
            app.UniqueThAxes.YTick = [0 1];
            app.SinglecellevaluationPanel = uipanel(app.GridLayout);
            app.SinglecellevaluationPanel.Title = 'Single cell evaluation';
            app.EvaluateButton = uibutton(app.SinglecellevaluationPanel, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.ThModeButtonGroup = uibuttongroup(app.SinglecellevaluationPanel);
            app.ThModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThModeButtonGroupSelectionChanged, true);
            app.globalButton = uiradiobutton(app.ThModeButtonGroup);
            app.globalButton.Text = 'global';
            app.uniqueButton = uiradiobutton(app.ThModeButtonGroup);
            app.uniqueButton.Text = 'unique';
            app.uniqueButton.Value = true;
            app.GlobalThresholdField = uieditfield(app.SinglecellevaluationPanel, 'text');
            app.GlobalThresholdField.ValueChangedFcn = createCallbackFcn(app, @GlobalThresholdFieldValueChanged, true);
            app.GlobalThresholdField.Editable = 'off';
            app.pmLabel_2 = uilabel(app.SinglecellevaluationPanel);
            app.pmLabel_2.Text = 'pm';
            app.ThresholdingListBoxLabel = uilabel(app.SinglecellevaluationPanel);
            app.ThresholdingListBoxLabel.Text = 'Thresholding';
            app.UniqueEvalButtonGroup = uibuttongroup(app.SinglecellevaluationPanel);
            app.UniqueEvalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UniqueEvalButtonGroupSelectionChanged, true);
            app.HeLaRGDButton = uiradiobutton(app.UniqueEvalButtonGroup);
            app.HeLaRGDButton.Text = 'HeLa RGD';
            app.HeLaRGDButton.Value = true;
            app.HeLaCombinedButton = uiradiobutton(app.UniqueEvalButtonGroup);
            app.HeLaCombinedButton.Text = 'HeLa Comb';
            app.CommunicationText = uitextarea(app.GridLayout);
            app.CommunicationText.Value = {'Hello Friend! Good luck with your experiments!'};
            app.FiltercellsPanel = uipanel(app.GridLayout);
            app.FiltercellsPanel.Title = 'Filter cells';
            app.FiltercellsButton = uibutton(app.FiltercellsPanel, 'state');
            app.FiltercellsButton.ValueChangedFcn = createCallbackFcn(app, @FiltercellsButtonValueChanged, true);
            app.FiltercellsButton.Text = 'Filter cells';
            app.SelectallButton = uibutton(app.FiltercellsPanel, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.ResetselectionButton = uibutton(app.FiltercellsPanel, 'push');
            app.ResetselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetselectionButtonPushed, true);
            app.ResetselectionButton.Text = 'Reset selection';
            app.ShiftclickcellstoremovefromselectionLabel = uilabel(app.FiltercellsPanel);
            app.ShiftclickcellstoremovefromselectionLabel.Text = '(Shift+click cells to remove from selection)';
            app.FindcellsPanel = uipanel(app.GridLayout);
            app.FindcellsPanel.Title = 'Find cells';
            app.DetectButton = uibutton(app.FindcellsPanel, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.DetectionthresholdEditFieldLabel = uilabel(app.FindcellsPanel);
            app.DetectionthresholdEditFieldLabel.Text = 'Detection threshold';
            app.DetectionthresholdEditField = uieditfield(app.FindcellsPanel, 'numeric');
            app.DetectionthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @DetectionthresholdEditFieldValueChanged, true);
            app.DetectionthresholdEditField.Value = 100;
            app.pmLabel_3 = uilabel(app.FindcellsPanel);
            app.pmLabel_3.Text = 'pm';
            % Show the figure after all components are created
            app.CardioAlpha.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cardio_raw_core

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CardioAlpha)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CardioAlpha)
        end
    end
end