classdef global_bg_corr_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ThresholdmeanSliderLabel   matlab.ui.control.Label
        ThresholdmeanSlider        matlab.ui.control.Slider
        ApplyButton                matlab.ui.control.Button
        SubplotPanel               matlab.ui.container.Panel
        nmLabel                    matlab.ui.control.Label
        ThresholdwidthSliderLabel  matlab.ui.control.Label
        ThresholdwidthSlider       matlab.ui.control.Slider
        nmLabel_2                  matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp          % The main app (cardio_eval_app)
        last_frame          % Last frame of the loaded measurement in main app
        threshold           % The selected threshold for global background correction
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app, last_frame)
            app.CallingApp = main_app;
            app.last_frame = last_frame;
            
            app.SubplotPanel.AutoResizeChildren = 'off';
            ax1 = subplot(1,2,1,'Parent',app.SubplotPanel);
            ax2 = subplot(1,2,2,'Parent',app.SubplotPanel);
            
            medWL = double(median(last_frame(:)))
            
            imagesc(ax1,last_frame);
            caxis(ax1,[medWL-1,medWL+1]);
            imagesc(ax2,last_frame);
            
            set(ax1,'visible','off')
            set(ax2,'visible','off')
            pbaspect(ax1,[1 1 1]);
            pbaspect(ax2,[1 1 1]);
%             app.ThresholdmeanSlider.Limits = [double(min(last_frame(:))),double(max(last_frame(:)))];
            
            
            app.ThresholdmeanSlider.Limits = [medWL-2, medWL+2];
        end

        % Value changing function: ThresholdmeanSlider
        function ThresholdmeanSliderValueChanging(app, event)
            ax2 = subplot(1,2,2,'Parent',app.SubplotPanel);
            mean = event.Value;
            halfwidth=app.ThresholdwidthSlider.Value/2;
            BGarea = (app.last_frame>mean-halfwidth) & (app.last_frame<mean+halfwidth);
            imagesc(ax2,BGarea);
            set(ax2,'visible','off')
            pbaspect(ax2,[1 1 1]);
        end

        % Value changing function: ThresholdwidthSlider
        function ThresholdwidthSliderValueChanging(app, event)
            ax2 = subplot(1,2,2,'Parent',app.SubplotPanel);
            mean = app.ThresholdmeanSlider.Value;
            halfwidth=event.Value/2;
            BGarea = (app.last_frame>mean-halfwidth) & (app.last_frame<mean+halfwidth);
            imagesc(ax2,BGarea);
            set(ax2,'visible','off')
            pbaspect(ax2,[1 1 1]);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Options button in main app
            app.CallingApp.GlobalbackgroundcorrectionButton.Enable = 'on';
            delete(app)
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if strcmp(app.CallingApp.mode,'WL')
                app.CallingApp.pic_corr = 1000.*global_background_correction_windowed(app.CallingApp.pic,app.ThresholdmeanSlider.Value,app.ThresholdwidthSlider.Value); %convert to pm after correction
                app.CallingApp.mode = 'WS';
            else
                app.CallingApp.pic_corr = global_background_correction(app.CallingApp.pic,app.ThresholdmeanSlider.Value);
            end
            
            for i=1:size(app.CallingApp.time,1)
                app.CallingApp.avgWS(i)=mean(reshape(app.CallingApp.pic_corr(:,:,i),1,[]));
            end
            
            app.CallingApp.refresh_avg_kin_plot();
            app.CallingApp.detection_th = app.ThresholdmeanSlider.Value;
            
            % Delete the dialog box  
            app.CallingApp.GlobalbackgroundcorrectionButton.Enable = 'on';
            delete(app)  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ThresholdmeanSliderLabel = uilabel(app.UIFigure);
            app.ThresholdmeanSliderLabel.Text = {'Threshold'; 'mean'};
            app.ThresholdmeanSlider = uislider(app.UIFigure);
            app.ThresholdmeanSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdmeanSliderValueChanging, true);
            app.ThresholdmeanSlider.MinorTicks = [];
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SubplotPanel = uipanel(app.UIFigure);
            app.nmLabel = uilabel(app.UIFigure);
            app.nmLabel.Text = 'nm';
            app.ThresholdwidthSliderLabel = uilabel(app.UIFigure);
            app.ThresholdwidthSliderLabel.Text = {'Threshold'; 'width'};
            app.ThresholdwidthSlider = uislider(app.UIFigure);
            app.ThresholdwidthSlider.Limits = [0 2];
            app.ThresholdwidthSlider.MajorTicks = [0 0.25 0.5 0.75 1 1.25 1.5 1.75 2];
            app.ThresholdwidthSlider.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1', '1.25', '1.5', '1.75', '2'};
            app.ThresholdwidthSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdwidthSliderValueChanging, true);
            app.ThresholdwidthSlider.MinorTicks = [];
            app.ThresholdwidthSlider.Value = 0.5;
            app.nmLabel_2 = uilabel(app.UIFigure);
            app.nmLabel_2.Text = 'nm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = global_bg_corr_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end